classdef HW1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        B10831020WhackamoleLabel  matlab.ui.control.Label
        but_br                    matlab.ui.control.Button
        but_bl                    matlab.ui.control.Button
        but_tr                    matlab.ui.control.Button
        but_tl                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_img; % Image selected to be displayed by user
        m_IconParts % cell array contains 4 slices of the image as the icon of the 4 buttons
        m_RedOrder; % the order the button turning red
        m_RedButtonPressed; % flag checking if the button turned red is pressed
        m_TurnRedIdx;
    end
    
    methods (Access = private)
        function m_SetButtonIcon(app)
            [fName, pName] = uigetfile({'*.png'; '*.jpeg'; '*.jpg'}, "MultiSelect", "off");
            app.m_img = imread([pName  fName]);
            [nRow, nCol, ~] = size(app.m_img);
            imgVrtBoundaryIdx = floor(nRow / 2);
            imgHozBoundaryIdx = floor(nCol / 2);
            
            app.m_IconParts{1} = app.m_img(1 : imgVrtBoundaryIdx, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{2} = app.m_img(1 :imgVrtBoundaryIdx, imgHozBoundaryIdx : end, :);
            app.m_IconParts{3} = app.m_img(imgVrtBoundaryIdx : end, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{4} = app.m_img(imgVrtBoundaryIdx : end, imgHozBoundaryIdx : end);
        end
        
        function m_PlayGame(app)
            buttons = {app.but_tl, app.but_tr, app.but_bl, app.but_br};
            for hit = 1:4
                app.m_TurnRedIdx = app.m_RedOrder(hit);
                buttons{app.m_TurnRedIdx}.BackgroundColor = 'r';
                while app.m_RedButtonPressed == false
                    % app.m_RedButtonPressed flag will turn true when the
                    % right button is pressed by user. Value is modified by
                    % the callback function of each button
                    %
                    % Stay in the loop and do nothing until the correct
                    % button is pressed
                end
                buttons{app.m_TurnRedIdx}.Icon = app.m_IconParts{app.m_TurnRedIdx};
                app.m_RedButtonPressed = false;
            end
        end

        function m_MatchButtonPressed(app, pressedIdx)
            if pressedIdx == app.m_TurnRedIdx
                app.m_RedButtonPressed = true;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.m_SetButtonIcon();
            app.m_RedButtonPressed = false;
            app.m_RedOrder = randperm(4);
            app.m_PlayGame();
        end

        % Button pushed function: but_tl
        function but_tlButtonPushed(app, event)
            buttonIdx = 1;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_tr
        function but_trButtonPushed(app, event)
            buttonIdx = 2;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_bl
        function but_blButtonPushed(app, event)
            buttonIdx = 3;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_br
        function but_brButtonPushed(app, event)
            buttonIdx = 4;
            app.m_MatchButtonPressed(buttonIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.but_tl = uibutton(app.UIFigure, 'push');
            app.but_tl.ButtonPushedFcn = createCallbackFcn(app, @but_tlButtonPushed, true);
            app.but_tl.Text = '';
            app.but_tr = uibutton(app.UIFigure, 'push');
            app.but_tr.ButtonPushedFcn = createCallbackFcn(app, @but_trButtonPushed, true);
            app.but_tr.Text = '';
            app.but_bl = uibutton(app.UIFigure, 'push');
            app.but_bl.ButtonPushedFcn = createCallbackFcn(app, @but_blButtonPushed, true);
            app.but_bl.Text = '';
            app.but_br = uibutton(app.UIFigure, 'push');
            app.but_br.ButtonPushedFcn = createCallbackFcn(app, @but_brButtonPushed, true);
            app.but_br.Text = '';
            app.B10831020WhackamoleLabel = uilabel(app.UIFigure);
            app.B10831020WhackamoleLabel.Text = 'B10831020: Whack-a-mole';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end